classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image2                       matlab.ui.control.Image
        h1Label                      matlab.ui.control.Label
        h2Label                      matlab.ui.control.Label
        Hint                         matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        Transformh1jpgButton         matlab.ui.control.Button
        Transformh2jpgButton         matlab.ui.control.Button
        Points1                      matlab.ui.control.Label
        Points2                      matlab.ui.control.Label
        Image1                       matlab.ui.control.Image
        SelectedPointsLabel          matlab.ui.control.Label
        SelectedPointsLabel_2        matlab.ui.control.Label
        TransformMatrixLabel         matlab.ui.control.Label
        Matrix1To2                   matlab.ui.control.ListBox
        TransformMatrixListBoxLabel  matlab.ui.control.Label
        Matrix2To1                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Image1ClickCount = 0;
        Image2ClickCount = 0;
        Image1Points = zeros(4, 2);
        Image2Points = zeros(4, 2);
        MatrixOne2Two = zeros(3, 3);
        MatrixTwo2One = zeros(3, 3);
        Image1Ratio = 1;
        Image2Ratio = 2;
    end
    
    methods (Access = private)
        function results = resetApp(app)
            app.Image1ClickCount = 0;
            app.Image2ClickCount = 0;
            app.Points1.Text = "";
            app.Points2.Text = "";
            app.Hint.Text = "Please select four points on both h1.jpg and h2.jpg";
            app.Matrix1To2.Items = {};
            app.Matrix2To1.Items = {};
            app.Transformh1jpgButton.Visible = false;
            app.Transformh2jpgButton.Visible = false;
            app.Matrix1To2.Visible = false;
            app.Matrix2To1.Visible = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image1Ratio = 1200 / app.Image1.Position(3);
            app.Image2Ratio = 850 / app.Image2.Position(3);
            resetApp(app);
        end

        % Image clicked function: Image1
        function Image1Clicked(app, event)
            if app.Image1ClickCount < 4
                position = get(0, 'PointerLocation');
                app.Image1ClickCount = app.Image1ClickCount + 1;
                position_in_image = round((position - app.Image1.Position(1:2) - app.UIFigure.Position(1:2)) * app.Image1Ratio);
                position_in_image = [position_in_image(1) 1200-position_in_image(2)];
                app.Image1Points(app.Image1ClickCount, :) = position_in_image;
                app.Points1.Text = app.Points1.Text + "(" + num2str(position_in_image(1)) + ", " + num2str(position_in_image(2)) + ") ";
            end
            if app.Image1ClickCount >= 4
                if app.Image2ClickCount >= 4
                    app.Hint.Text = "You can transform images now";
                    app.Transformh1jpgButton.Visible = true;
                    app.Transformh2jpgButton.Visible = true;
                else
                    app.Hint.Text = "Please select points on h2.jpg";
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetApp(app);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            if app.Image2ClickCount < 4
                position = get(0, 'PointerLocation');
                app.Image2ClickCount = app.Image2ClickCount + 1;
                position_in_image = round((position - app.Image2.Position(1:2) - app.UIFigure.Position(1:2)) * app.Image2Ratio);
                position_in_image = [position_in_image(1) 850-position_in_image(2)];
                app.Image2Points(app.Image2ClickCount, :) = position_in_image;
                app.Points2.Text = app.Points2.Text + "(" + num2str(position_in_image(1)) + ", " + num2str(position_in_image(2)) + ") ";
            end
            if app.Image2ClickCount >= 4
                if app.Image1ClickCount >= 4
                    app.Hint.Text = "You can transform images now";
                    app.Transformh1jpgButton.Visible = true;
                    app.Transformh2jpgButton.Visible = true;
                else
                    app.Hint.Text = "Please select points on h1.jpg";
                end
            end
        end

        % Button pushed function: Transformh1jpgButton
        function Transformh1jpgButtonPushed(app, event)
            o1 = app.Image1Points(1, :);
            o2 = app.Image1Points(2, :);
            o3 = app.Image1Points(3, :);
            o4 = app.Image1Points(4, :);
            t1 = app.Image2Points(1, :);
            t2 = app.Image2Points(2, :);
            t3 = app.Image2Points(3, :);
            t4 = app.Image2Points(4, :);
            a = [o1(1) o1(2) 1 0 0 0 -t1(1)*o1(1) -t1(1)*o1(2) -t1(1);
                0 0 0 o1(1) o1(2) 1 -t1(2)*o1(1) -t1(2)*o1(2) -t1(2);
                o2(1) o2(2) 1 0 0 0 -t2(1)*o2(1) -t2(1)*o2(2) -t2(1);
                0 0 0 o2(1) o2(2) 1 -t2(2)*o2(1) -t2(2)*o2(2) -t2(2);
                o3(1) o3(2) 1 0 0 0 -t3(1)*o3(1) -t3(1)*o3(2) -t3(1);
                0 0 0 o3(1) o3(2) 1 -t3(2)*o3(1) -t3(2)*o3(2) -t3(2);
                o4(1) o4(2) 1 0 0 0 -t4(1)*o4(1) -t4(1)*o4(2) -t4(1);
                0 0 0 o4(1) o4(2) 1 -t4(2)*o4(1) -t4(2)*o4(2) -t4(2)];
            [U, S, V] = svd(a);
            V_T = V';
            transform_matrix = V_T(9, :);
            app.Matrix1To2.Items = cellstr(num2str(transform_matrix'));
            app.Matrix1To2.Visible = true;
            app.MatrixOne2Two = reshape(transform_matrix, [3, 3]);
            transformed = imwarp(imread("h1.jpg"), projective2d(app.MatrixOne2Two));
            imshow(transformed);
        end

        % Button pushed function: Transformh2jpgButton
        function Transformh2jpgButtonPushed(app, event)
            o1 = app.Image2Points(1, :);
            o2 = app.Image2Points(2, :);
            o3 = app.Image2Points(3, :);
            o4 = app.Image2Points(4, :);
            t1 = app.Image1Points(1, :);
            t2 = app.Image1Points(2, :);
            t3 = app.Image1Points(3, :);
            t4 = app.Image1Points(4, :);
            a = [o1(1) o1(2) 1 0 0 0 -t1(1)*o1(1) -t1(1)*o1(2) -t1(1);
                0 0 0 o1(1) o1(2) 1 -t1(2)*o1(1) -t1(2)*o1(2) -t1(2);
                o2(1) o2(2) 1 0 0 0 -t2(1)*o2(1) -t2(1)*o2(2) -t2(1);
                0 0 0 o2(1) o2(2) 1 -t2(2)*o2(1) -t2(2)*o2(2) -t2(2);
                o3(1) o3(2) 1 0 0 0 -t3(1)*o3(1) -t3(1)*o3(2) -t3(1);
                0 0 0 o3(1) o3(2) 1 -t3(2)*o3(1) -t3(2)*o3(2) -t3(2);
                o4(1) o4(2) 1 0 0 0 -t4(1)*o4(1) -t4(1)*o4(2) -t4(1);
                0 0 0 o4(1) o4(2) 1 -t4(2)*o4(1) -t4(2)*o4(2) -t4(2)];
            [U, S, V] = svd(a);
            V_T = V';
            transform_matrix = V_T(9, :);
            app.Matrix2To1.Items = cellstr(num2str(transform_matrix'));
            app.Matrix2To1.Visible = true;
            app.MatrixTwo2One = reshape(transform_matrix, [3, 3]);
            transformed = imwarp(imread("h2.jpg"), projective2d(app.MatrixTwo2One));
            imshow(transformed);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'h2.jpg';
            app.h1Label = uilabel(app.UIFigure);
            app.h1Label.Text = 'h1.jpg';
            app.h2Label = uilabel(app.UIFigure);
            app.h2Label.Text = 'h2.jpg';
            app.Hint = uilabel(app.UIFigure);
            app.Hint.Text = 'Please select four points on both h1.jpg and h2.jpg';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Transformh1jpgButton = uibutton(app.UIFigure, 'push');
            app.Transformh1jpgButton.ButtonPushedFcn = createCallbackFcn(app, @Transformh1jpgButtonPushed, true);
            app.Transformh1jpgButton.Text = 'Transform h1.jpg';
            app.Transformh2jpgButton = uibutton(app.UIFigure, 'push');
            app.Transformh2jpgButton.ButtonPushedFcn = createCallbackFcn(app, @Transformh2jpgButtonPushed, true);
            app.Transformh2jpgButton.Text = 'Transform h2.jpg';
            app.Points1 = uilabel(app.UIFigure);
            app.Points1.Text = '';
            app.Points2 = uilabel(app.UIFigure);
            app.Points2.Text = '';
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image1.ImageSource = 'h1.jpg';
            app.SelectedPointsLabel = uilabel(app.UIFigure);
            app.SelectedPointsLabel.Text = 'Selected Points:';
            app.SelectedPointsLabel_2 = uilabel(app.UIFigure);
            app.SelectedPointsLabel_2.Text = 'Selected Points:';
            app.TransformMatrixLabel = uilabel(app.UIFigure);
            app.TransformMatrixLabel.Text = 'Transform Matrix';
            app.Matrix1To2 = uilistbox(app.UIFigure);
            app.Matrix1To2.Items = {};
            app.Matrix1To2.Value = {};
            app.TransformMatrixListBoxLabel = uilabel(app.UIFigure);
            app.TransformMatrixListBoxLabel.Text = 'Transform Matrix';
            app.Matrix2To1 = uilistbox(app.UIFigure);
            app.Matrix2To1.Items = {};
            app.Matrix2To1.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end